classdef MApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EBSDDataAnalysisPanel      matlab.ui.container.Panel
        DataTable                  matlab.ui.control.Table
        CurrentData                matlab.ui.control.EditField
        CurrentDataEditFieldLabel  matlab.ui.control.Label
        ExportDataButton           matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        ImportEBSDDataButton       matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ImportedData % EBSD Data imported by import_data
        FileName % Name of the File, that got imported 
        Phases_Id % List, containing each Id of the corresponding phase
        Phases % List, containing all phases 
        Points % List, containing the number of data-points each phase 
        Symmetry % Symmetry of all phases 
        Elements % ???
        Angles % List, containing all angles a,b,c of each phase 
        Color % List, containing RGB values of each phase 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportEBSDDataButton
        function ImportEBSDDataButtonPushed(app, event)
            app.DataTable.Data = [];
            removeStyle(app.DataTable);

            % create a style
            s = uistyle('HorizontalAlignment','left');           
            addStyle(app.DataTable,s)

            % Import EBSD Data using the import_data function 
            [data, fileName] = import_data();
            app.ImportedData = data; 
            app.FileName = fileName;

            % Displaying the Name of the imported File
            app.CurrentData.Value = app.FileName;

            % fill UIAxes with the plotted data, only gets update once here
            % after pressing the 'Import EBSD' button
            % !!! Figure created by plot() cannot be closed
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.ImportedData,'parent',app.UIAxes);

            
            % fill ... with values 
            app.Phases_Id = app.ImportedData.phaseMap;

            % Start filling the Table, containing information about our
            % EBSD data
            
            %Iterate though all phases and create a new entry for each
            l = length(app.ImportedData.CSList);
            
            % add case for NotIndexed 
            for i = 1:1:l
                % get the name of the phase' mineral
                mineral_name = app.ImportedData
                app.Phases{i} = mineral_name;

                % shorten mineral_name to the first 3 letters of its name
                % in order to get its # of orientation points
                % !!!!!!! Maybe change short_mineral_name to something more
                % flexible, to avoid error with weird mineral names
                short_mineral_name = mineral_name(1:3);

                ImportedCrystalSymmetry = app.ImportedData(short_mineral_name).CS;


                % get the # of orientations/data points of the phase
                app.Points = app.ImportedData(short_mineral_name).orientations.size(1);
                
                % get the kind of symmetry/pointGroup of each phase
                app.Symmetry = app.ImportedData(short_mineral_name).CS.pointGroup;
                
                % get the number of symmetry elements of each phase 
                app.Elements = numSym(ImportedCrystalSymmetry);

                % get the angles of each phase
                app.Angles.a = app.ImportedData(short_mineral_name).CS.alpha;
                app.Angles.b = app.ImportedData(short_mineral_name).CS.beta;
                app.Angles.c = app.ImportedData(short_mineral_name).CS.gamma;

                % get the color of the phase
                % change the color of the corresponding cell 
                s = uistyle;
                app.Color = app.ImportedData(short_mineral_name).CS.color;
                s.BackgroundColor = [app.Color];
                addStyle(app.DataTable,s,"cell",[i-1,8;i-1,9;i-1,10]);
                    
                newEntry = {char(app.ImportedData.mineralList(i)),app.Points,app.Symmetry,app.Elements, app.Angles.a,app.Angles.b,app.Angles.c,app.Color(1),app.Color(2),app.Color(3)};
                app.DataTable.Data = [app.DataTable.Data;newEntry];
            end


        end

        % Cell edit callback: DataTable
        function DataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            changed_mineral_name = app.DataTable.Data{indices(1), 1};

            % check if color (R,G,B) has been changed
            if indices(2) >= 8
                % R-Value has been changed
                if indices(2) == 8
                    app.ImportedData(changed_mineral_name).CS.color(1) = newData;

                % G-Value has been changed
                elseif indices(2) == 9
                    app.ImportedData(changed_mineral_name).CS.color(2) = newData;
                    
                % B-Value has been changed
                elseif indices(2) == 10
                    app.ImportedData(changed_mineral_name).CS.color(3) = newData;
                   
                end
                
                % update table(DataTable) color 
                s = uistyle;
                s.BackgroundColor = [app.ImportedData(changed_mineral_name).CS.color];
                changedCell = indices(1);
                addStyle(app.DataTable,s,"cell",[changedCell,8;changedCell,9;changedCell,10]);
                
            % Check if angles (alpha,beta,gamma) have been changed
            elseif (5 <= indices(2)) && (indices(2) <= 7)
                
                % Values cant be assigned yet

                % Alpha-Value has been changed
                if indices(2) == 5
                    app.ImportedData(changed_mineral_name).CS.alpha = newData;
                % Beta-Value has been changed
                elseif indices(2) == 6
                    app.ImportedData(changed_mineral_name).CS.beta = newData;
                % Gamma-Value has been changed
                elseif indices(2) == 7
                    app.ImportedData(changed_mineral_name).CS.gamma = newData;
                end

            % Check if type of symmetry has been changed
            elseif indices(2) == 3


            end

            
            % update plot in UIAxes (still opens plot windows of plot()
            % function) 
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.ImportedData,'parent',app.UIAxes);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EBSDDataAnalysisPanel = uipanel(app.UIFigure);
            app.EBSDDataAnalysisPanel.Title = 'EBSD Data Analysis';
            app.UIAxes = uiaxes(app.EBSDDataAnalysisPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ImportEBSDDataButton = uibutton(app.EBSDDataAnalysisPanel, 'push');
            app.ImportEBSDDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEBSDDataButtonPushed, true);
            app.ImportEBSDDataButton.Text = 'Import EBSD-Data';
            app.Label = uilabel(app.EBSDDataAnalysisPanel);
            app.ExportDataButton = uibutton(app.EBSDDataAnalysisPanel, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.CurrentDataEditFieldLabel = uilabel(app.EBSDDataAnalysisPanel);
            app.CurrentDataEditFieldLabel.Text = 'Current Data';
            app.CurrentData = uieditfield(app.EBSDDataAnalysisPanel, 'text');
            app.CurrentData.Editable = 'off';
            app.DataTable = uitable(app.EBSDDataAnalysisPanel);
            app.DataTable.ColumnName = {'PhaseID'; 'Phase'; '# Orientations'; 'Symmetry'; 'Elements'; 'alpha'; 'beta'; 'gamma'; 'Color (R)'; 'Color (G)'; 'Color(B)'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [false false true false true true true true true true];
            app.DataTable.CellEditCallback = createCallbackFcn(app, @DataTableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end